classdef MAE547_Final_Project_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SolveButton                   matlab.ui.control.Button
        ILinkValue_6                  matlab.ui.control.NumericEditField
        ILinkValue_5                  matlab.ui.control.NumericEditField
        ILinkValue_4                  matlab.ui.control.NumericEditField
        ILinkValue_3                  matlab.ui.control.NumericEditField
        ILinkValue_2                  matlab.ui.control.NumericEditField
        ILinkLabel_2                  matlab.ui.control.Label
        linkNumber                    matlab.ui.control.Label
        LinksTableLabel               matlab.ui.control.Label
        ILinkLabel                    matlab.ui.control.Label
        mLinkLabel                    matlab.ui.control.Label
        ILinkValue                    matlab.ui.control.NumericEditField
        mLinkValue                    matlab.ui.control.NumericEditField
        JointsTableLabel              matlab.ui.control.Label
        MainMenus                     matlab.ui.container.ButtonGroup
        IFCIButton                    matlab.ui.control.ToggleButton
        IFCCButton                    matlab.ui.control.ToggleButton
        DynamicsButton                matlab.ui.control.ToggleButton
        MAE547Team1FinalProjectLabel  matlab.ui.control.Label
        thetaLabel                    matlab.ui.control.Label
        dLabel                        matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        aLabel                        matlab.ui.control.Label
        thetaValue                    matlab.ui.control.NumericEditField
        dValue                        matlab.ui.control.NumericEditField
        alphaValue                    matlab.ui.control.NumericEditField
        aValue                        matlab.ui.control.NumericEditField
        ofJointsSpinner               matlab.ui.control.Spinner
        ofJointsSpinnerLabel          matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        dEquationLabel                matlab.ui.control.Label
        IJointLabel                   matlab.ui.control.Label
        IJointValue                   matlab.ui.control.NumericEditField
        dEquation                     matlab.ui.control.EditField
        icDoubleDotLabel              matlab.ui.control.Label
        icDoubleDotValue              matlab.ui.control.NumericEditField
        icDotLabel                    matlab.ui.control.Label
        icDotValue                    matlab.ui.control.NumericEditField
        jointNumber                   matlab.ui.control.Label
        vfLabel                       matlab.ui.control.Label
        sfLabel                       matlab.ui.control.Label
        krLabel                       matlab.ui.control.Label
        vfValue                       matlab.ui.control.NumericEditField
        sfValue                       matlab.ui.control.NumericEditField
        krValue                       matlab.ui.control.NumericEditField
        mJointLabel                   matlab.ui.control.Label
        mJointValue                   matlab.ui.control.NumericEditField
        rpValue                       matlab.ui.control.DropDown
        ContextMenu                   matlab.ui.container.ContextMenu
        aMenu                         matlab.ui.container.Menu
        alphaMenu                     matlab.ui.container.Menu
        dMenu                         matlab.ui.container.Menu
        thetaMenu                     matlab.ui.container.Menu
        rpMenu                        matlab.ui.container.Menu
        mJointMenu                    matlab.ui.container.Menu
        IJointMenu                    matlab.ui.container.Menu
        krMenu                        matlab.ui.container.Menu
        sfMenu                        matlab.ui.container.Menu
        vfMenu                        matlab.ui.container.Menu
        icDotMenu                     matlab.ui.container.Menu
        mLinkMenu                     matlab.ui.container.Menu
        ILinkMenu                     matlab.ui.container.Menu
        icDoubleDotMenu               matlab.ui.container.Menu
        dEquationMenu                 matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        jointCount = 1;
        jointCountPrevious = 1;
        menuPrevious = 1;
        menuCurrent = 1;
        solving = 0;

        aValues = [];
        alphaValues = [];
        dValues = [];
        thetaValues = [];

        icDotValues = [];
        icDoubleDotValues = [];
        dEquations = {};

        mJointValues = [];
        IJointValues = [];
        rpValues = cell(1);
        krValues = [];
        sfValues = [];
        vfValues = [];
        mLinkValues = [];
        ILinkValues = [];

        cms_a = {};
        cms_alpha = {};
        cms_d = {};
        cms_theta = {};
        cms_rp = {};
        cms_icDot = {};
        cms_icDoubleDot = {};
        cms_dEquation = {};
        cms_mJoint = {};
        cms_IJoint = {};
        cms_kr = {};
        cms_sf = {};
        cms_vf = {};
        cms_mLink = {};
        cms_ILink = {};

    end

    methods (Access = private)
        function updateContextMenu(app, i, type)
            switch type
                case 'a'
                    app.aMenu(i).Text = sprintf('Joint %d a-Value: %s m', i, num2str(app.aValue(i).Value));
                case 'alpha'
                    app.alphaMenu(i).Text = sprintf('Joint %d alpha-Value: %s rad', i, num2str(app.alphaValue(i).Value));
                case 'd'
                    if strcmp(app.rpValues{i}, 'Revolut')
                        app.dMenu(i).Text = sprintf('Joint %d d-Value: %s m', i, num2str(app.dValue(i).Value));
                    else
                        app.dMenu(i).Text = sprintf('Joint %d d-Value: d%s + %s m', i, char(8320 + i), num2str(app.dValue(i).Value));
                    end
                case 'theta'
                    if strcmp(app.rpValues{i}, 'Revolut')
                        app.thetaMenu(i).Text = sprintf('Joint %d theta-Value: θ%s + %s rad', i, char(8320 + i), num2str(app.thetaValue(i).Value));
                    else
                        app.thetaMenu(i).Text = sprintf('Joint %d theta-Value: %s rad', i, num2str(app.thetaValue(i).Value));
                    end
                case 'rp'
                    if i <= length(app.rpValues) && ~isempty(app.rpValues{i})
                        app.rpMenu(i).Text = sprintf('Joint %d is: %s', i, app.rpValues{i});
                    else
                        app.rpMenu(i).Text = sprintf('Joint %d is: Revolut', i);
                    end
                case 'icDot'
                    if strcmp(app.rpValues{i}, 'Revolut')
                        app.icDotMenu(i).Text = sprintf('Joint %d Initial Angular Velocity: %s m/s', i, num2str(app.icDotValue(i).Value));
                    else
                        app.icDotMenu(i).Text = sprintf('Joint %d Initial Velocity: %s m/s', i, num2str(app.icDotValue(i).Value));
                    end
                case 'icDoubleDot'
                    if strcmp(app.rpValues{i}, 'Revolut')
                        app.icDoubleDotMenu(i).Text = sprintf('Joint %d Initial Angular Acceleration: %s m/s²', i, num2str(app.icDoubleDotValue(i).Value));
                    else
                        app.icDoubleDotMenu(i).Text = sprintf('Joint %d Initial Acceleration: %s m/s²', i, num2str(app.icDoubleDotValue(i).Value));
                    end
                case 'dEquation'
                    app.dEquationMenu(i).Text = sprintf('Joint %d τ: %s', i, num2str(app.dEquation(i).Value));
                case 'mJoint'
                    app.mJointMenu(i).Text = sprintf('Joint %d Mass: %s kg', i, num2str(app.mJointValue(i).Value));
                case 'IJoint'
                    app.IJointMenu(i).Text = sprintf('Joint %d Moment of Inertia: %s kg·m²', i, num2str(app.IJointValue(i).Value));
                case 'kr'
                    app.krMenu(i).Text = sprintf('Joint %d Gear Ratio: %s', i, num2str(app.krValue(i).Value));
                case 'sf'
                    app.sfMenu(i).Text = sprintf('Joint %d Static Friction: %s', i, num2str(app.sfValue(i).Value));
                case 'vf'
                    app.vfMenu(i).Text = sprintf('Joint %d Viscous Friction: %s', i, num2str(app.vfValue(i).Value));
                case 'mLink'
                    app.mLinkMenu(i).Text = sprintf('Link %d Mass: %s kg', i, num2str(app.mLinkValue(i).Value));
                case 'ILink'
                    app.ILinkMenu(i).Text = sprintf('Link %d Moment of Inertia: %s kg·m²', i, num2str(app.ILinkValue(i).Value));
            end
        end

        function updateMainMenu(app)

            fprintf('Joint Count = %d\n',app.jointCount);
            fprintf('Previous Joint Count = %d\n',app.jointCountPrevious);
            fprintf('%d to %d\n', app.menuPrevious, app.menuCurrent)

            if app.MainMenus.SelectedObject == app.DynamicsButton

                a = [app.jointCount; app.jointCountPrevious];

                for i = (min(a)+1):max(a)
                    %fprintf('i = %d\n',i);

                    if app.jointCountPrevious > app.jointCount
                        fprintf('%d\n', i)
                        delete(app.jointNumber(i));
                        delete(app.aValue(i));
                        delete(app.alphaValue(i));
                        delete(app.dValue(i));
                        delete(app.thetaValue(i));
                        delete(app.rpValue(i));

                        delete(app.icDotValue(i));
                        delete(app.icDoubleDotValue(i));
                        delete(app.dEquation(i));

                        delete(app.cms_a{i});
                        delete(app.cms_alpha{i});
                        delete(app.cms_d{i});
                        delete(app.cms_theta{i});
                        delete(app.cms_rp{i});
                        delete(app.cms_icDot{i});
                        delete(app.cms_icDoubleDot{i});
                        delete(app.cms_dEquation{i});

                    else
                        %fprintf('i = %d\n',i);
                        app.jointNumber(i) = uilabel(app.UIFigure, 'Text', num2str(i));
                        app.aValue(i) = uieditfield(app.UIFigure, 'numeric');
                        app.alphaValue(i) = uieditfield(app.UIFigure, 'numeric');
                        app.dValue(i) = uieditfield(app.UIFigure, 'numeric', 'Editable', 'on');
                        app.thetaValue(i) = uieditfield(app.UIFigure, 'numeric', 'Editable', 'on');
                        app.rpValue(i) = uidropdown(app.UIFigure, 'Items', {'Revolut', 'Prismatic'}, 'Value', 'Revolut');
                        app.icDotValue(i) = uieditfield(app.UIFigure, 'numeric');
                        app.icDoubleDotValue(i) = uieditfield(app.UIFigure, 'numeric');
                        app.dEquation(i) = uieditfield(app.UIFigure, 'text');
                        
                        app.aValue(i).ValueChangedFcn = createCallbackFcn(app, @aValueValueChanged, true);
                        app.alphaValue(i).ValueChangedFcn = createCallbackFcn(app, @alphaValueValueChanged, true);
                        app.dValue(i).ValueChangedFcn = createCallbackFcn(app, @dValueValueChanged, true);
                        app.thetaValue(i).ValueChangedFcn = createCallbackFcn(app, @thetaValueValueChanged, true);
                        app.rpValue(i).ValueChangedFcn = createCallbackFcn(app, @rpValueValueChanged, true);
                        app.icDotValue(i).ValueChangedFcn = createCallbackFcn(app, @icDotValueValueChanged, true);
                        app.icDoubleDotValue(i).ValueChangedFcn = createCallbackFcn(app, @icDoubleDotValueValueChanged, true);
                        app.dEquation(i).ValueChangedFcn = createCallbackFcn(app, @dEquationValueChanged, true);    
                        
                        if i == 10
                            app.jointNumber(i).Position = [((app.jointNumber(1).Position(1)) - 3), ((app.jointNumber(1).Position(2)) + 1) - 20*(i-1), app.jointNumber(1).Position(3), app.jointNumber(1).Position(4)];
                        else
                            app.jointNumber(i).Position = [((app.jointNumber(1).Position(1)) + 1), ((app.jointNumber(1).Position(2)) + 1) - 20*(i-1), app.jointNumber(1).Position(3), app.jointNumber(1).Position(4)];
                        end
                        app.aValue(i).Position = [((app.aValue(1).Position(1)) + 1), ((app.aValue(1).Position(2)) + 1) - 20*(i-1), app.aValue(1).Position(3), app.aValue(1).Position(4)];
                        app.alphaValue(i).Position = [((app.alphaValue(1).Position(1)) + 1), ((app.alphaValue(1).Position(2)) + 1) - 20*(i-1), app.alphaValue(1).Position(3), app.alphaValue(1).Position(4)];
                        app.dValue(i).Position = [((app.dValue(1).Position(1)) + 1), ((app.dValue(1).Position(2)) + 1) - 20*(i-1), app.dValue(1).Position(3), app.dValue(1).Position(4)];
                        app.thetaValue(i).Position = [((app.thetaValue(1).Position(1)) + 1), ((app.thetaValue(1).Position(2)) + 1) - 20*(i-1), app.thetaValue(1).Position(3), app.thetaValue(1).Position(4)]; 
                        app.rpValue(i).Position = [((app.rpValue(1).Position(1))), ((app.rpValue(1).Position(2))) - 20*(i-1), app.rpValue(1).Position(3), app.rpValue(1).Position(4)];
                        app.icDotValue(i).Position = [((app.icDotValue(1).Position(1))), ((app.icDotValue(1).Position(2))) - 20*(i-1), app.icDotValue(1).Position(3), app.icDotValue(1).Position(4)];
                        app.icDoubleDotValue(i).Position = [((app.icDoubleDotValue(1).Position(1))), ((app.icDoubleDotValue(1).Position(2))) - 20*(i-1), app.icDoubleDotValue(1).Position(3), app.icDoubleDotValue(1).Position(4)];
                        app.dEquation(i).Position = [((app.dEquation(1).Position(1))), ((app.dEquation(1).Position(2))) - 20*(i-1), app.dEquation(1).Position(3), app.dEquation(1).Position(4)];
                        
                        cm_a = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'a'));
                        app.aMenu(i) = uimenu(cm_a, 'Text', '');
                        app.aValue(i).ContextMenu = cm_a;
                        app.cms_a{i} = cm_a;
    
                        cm_alpha = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'alpha'));
                        app.alphaMenu(i) = uimenu(cm_alpha, 'Text', '');
                        app.alphaValue(i).ContextMenu = cm_alpha;
                        app.cms_alpha{i} = cm_alpha;
    
                        cm_d = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'd'));
                        app.dMenu(i) = uimenu(cm_d, 'Text', '');
                        app.dValue(i).ContextMenu = cm_d;
                        app.cms_d{i} = cm_d;
    
                        cm_theta = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'theta'));
                        app.thetaMenu(i) = uimenu(cm_theta, 'Text', '');
                        app.thetaValue(i).ContextMenu = cm_theta;
                        app.cms_theta{i} = cm_theta;
    
                        cm_rp = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'rp'));
                        app.rpMenu(i) = uimenu(cm_rp, 'Text', '');
                        app.rpValue(i).ContextMenu = cm_rp;
                        app.cms_rp{i} = cm_rp;

                        cm_icDot = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'icDot'));
                        app.icDotMenu(i) = uimenu(cm_icDot, 'Text', '');
                        app.icDotValue(i).ContextMenu = cm_icDot;
                        app.cms_icDot{i} = cm_icDot;
    
                        cm_icDoubleDot = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'icDoubleDot'));
                        app.icDoubleDotMenu(i) = uimenu(cm_icDoubleDot, 'Text', '');
                        app.icDoubleDotValue(i).ContextMenu = cm_icDoubleDot;
                        app.cms_icDoubleDot{i} = cm_icDoubleDot;
    
                        cm_dEquation = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'dEquation'));
                        app.dEquationMenu(i) = uimenu(cm_dEquation, 'Text', '');
                        app.dEquation(i).ContextMenu = cm_dEquation;
                        app.cms_dEquation{i} = cm_dEquation;
    
                        %app.aValue(i).Tooltip = sprintf('%s', num2str(app.aValue(i).Value));
    
                        if i <= length(app.aValues)
                            app.aValue(i).Value = app.aValues(i);
                        else
                            app.aValue(i).Value = 0;
                            app.aValues(i) = 0;
                        end
                        if i <= length(app.alphaValues)
                            app.alphaValue(i).Value = app.alphaValues(i);
                        else
                            app.alphaValue(i).Value = 0;
                            app.alphaValues(i) = 0;
                        end
                        if i <= length(app.dValues)
                            app.dValue(i).Value = app.dValues(i);
                        else
                            app.dValue(i).Value = 0;
                            app.dValues(i) = 0;
                        end
                        if i <= length(app.thetaValues)
                            app.thetaValue(i).Value = app.thetaValues(i);
                        else
                            app.thetaValue(i).Value = 0;
                            app.thetaValues(i) = 0;
                        end
                        if i <= length(app.rpValues) && ~isempty(app.rpValues{i})
                            fprintf('rpValues{%d} = "%s"\n', i, app.rpValues{i});
                            app.rpValue(i).Value = app.rpValues{i};
                        else
                            app.rpValue(i).Value = 'Revolut';
                            app.rpValues{i} = 'Revolut';
                        end
                        if i <= length(app.icDotValues)
                            app.icDotValue(i).Value = app.icDotValues(i);
                        else
                            app.icDotValue(i).Value = 0;
                            app.icDotValues(i) = 0;
                        end
                        if i <= length(app.icDoubleDotValues)
                            app.icDoubleDotValue(i).Value = app.icDoubleDotValues(i);
                        else
                            app.icDoubleDotValue(i).Value = 0;
                            app.icDoubleDotValues(i) = 0;
                        end
                        if i <= length(app.dEquations)
                            if ~isempty(app.dEquations{i})
                                app.dEquation(i).Value = app.dEquations{i};
                            else
                                app.dEquation(i).Value = '';
                                %app.dEquations(i) = '';
                            end
                        else
                            app.dEquation(i).Value = '';
                            %app.dEquations(i) = '';
                        end  
                    end
                end
                app.jointCountPrevious = app.jointCount;
                %app.menuPrevious = 1;
            end
            if app.MainMenus.SelectedObject == app.IFCCButton
                fprintf('%d to %d\n', app.jointCountPrevious, app.jointCount)

                a = [app.jointCount; app.jointCountPrevious];

                %if app.menuPrevious == 1
                    for i = (min(a)+1):max(a)
                        fprintf('%d\n', i)
                        if app.jointCountPrevious > app.jointCount
                            fprintf('del\n')
                            delete(app.jointNumber(i));
                            delete(app.aValue(i));
                            delete(app.alphaValue(i));
                            delete(app.dValue(i));
                            delete(app.thetaValue(i));
                            delete(app.rpValue(i));
    
                            delete(app.mJointValue(i));
                            delete(app.IJointValue(i));
                            delete(app.krValue(i));
                            delete(app.sfValue(i));
                            delete(app.vfValue(i));
                            delete(app.linkNumber(i));
                            delete(app.mLinkValue(i));
                            delete(app.ILinkValue(i));
    
                            delete(app.cms_a{i});
                            delete(app.cms_alpha{i});
                            delete(app.cms_d{i});
                            delete(app.cms_theta{i});
                            delete(app.cms_rp{i});
                            delete(app.cms_mJoint{i});
                            delete(app.cms_IJoint{i});
                            delete(app.cms_kr{i});
                            delete(app.cms_sf{i});
                            delete(app.cms_vf{i});
                            delete(app.cms_mLink{i});
                            delete(app.cms_ILink{i});
    
                        else
                            fprintf('add\n')
                            app.jointNumber(i) = uilabel(app.UIFigure, 'Text', num2str(i));
                            app.aValue(i) = uieditfield(app.UIFigure, 'numeric');
                            app.alphaValue(i) = uieditfield(app.UIFigure, 'numeric');
                            app.dValue(i) = uieditfield(app.UIFigure, 'numeric', 'Editable', 'on');
                            app.thetaValue(i) = uieditfield(app.UIFigure, 'numeric', 'Editable', 'on');
                            app.rpValue(i) = uidropdown(app.UIFigure, 'Items', {'Revolut', 'Prismatic'}, 'Value', 'Revolut');
                            app.mJointValue(i) = uieditfield(app.UIFigure, 'numeric');
                            app.IJointValue(i) = uieditfield(app.UIFigure, 'numeric');
                            app.krValue(i) = uieditfield(app.UIFigure, 'numeric');
                            app.sfValue(i) = uieditfield(app.UIFigure, 'numeric');
                            app.vfValue(i) = uieditfield(app.UIFigure, 'numeric');
                            app.linkNumber(i) = uilabel(app.UIFigure, 'Text', num2str(i));
                            app.mLinkValue(i) = uieditfield(app.UIFigure, 'numeric');
                            app.ILinkValue(i) = uieditfield(app.UIFigure, 'numeric');
        
                            app.aValue(i).ValueChangedFcn = createCallbackFcn(app, @aValueValueChanged, true);
                            app.alphaValue(i).ValueChangedFcn = createCallbackFcn(app, @alphaValueValueChanged, true);
                            app.dValue(i).ValueChangedFcn = createCallbackFcn(app, @dValueValueChanged, true);
                            app.thetaValue(i).ValueChangedFcn = createCallbackFcn(app, @thetaValueValueChanged, true);
                            app.rpValue(i).ValueChangedFcn = createCallbackFcn(app, @rpValueValueChanged, true);
                            app.mJointValue(i).ValueChangedFcn = createCallbackFcn(app, @mJointValueValueChanged, true);
                            app.IJointValue(i).ValueChangedFcn = createCallbackFcn(app, @IJointValueValueChanged, true);
                            app.krValue(i).ValueChangedFcn = createCallbackFcn(app, @krValueValueChanged, true);
                            app.sfValue(i).ValueChangedFcn = createCallbackFcn(app, @sfValueValueChanged, true);
                            app.vfValue(i).ValueChangedFcn = createCallbackFcn(app, @vfValueValueChanged, true);
                            app.mLinkValue(i).ValueChangedFcn = createCallbackFcn(app, @mLinkValueValueChanged, true);
                            app.ILinkValue(i).ValueChangedFcn = createCallbackFcn(app, @ILinkValueValueChanged, true);
        
                            if i == 10
                                app.jointNumber(i).Position = [((app.jointNumber(1).Position(1)) - 3), ((app.jointNumber(1).Position(2)) + 1) - 20*(i-1), app.jointNumber(1).Position(3), app.jointNumber(1).Position(4)];
                                app.linkNumber(i).Position = [((app.linkNumber(1).Position(1)) - 3), ((app.linkNumber(1).Position(2)) + 1) - 20*(i-1), app.linkNumber(1).Position(3), app.linkNumber(1).Position(4)];
                            else
                                app.jointNumber(i).Position = [((app.jointNumber(1).Position(1)) + 1), ((app.jointNumber(1).Position(2)) + 1) - 20*(i-1), app.jointNumber(1).Position(3), app.jointNumber(1).Position(4)];
                                app.linkNumber(i).Position = [((app.linkNumber(1).Position(1)) + 1), ((app.linkNumber(1).Position(2)) + 1) - 20*(i-1), app.linkNumber(1).Position(3), app.linkNumber(1).Position(4)];
                            end
                            app.aValue(i).Position = [((app.aValue(1).Position(1)) + 1), ((app.aValue(1).Position(2)) + 1) - 20*(i-1), app.aValue(1).Position(3), app.aValue(1).Position(4)];
                            app.alphaValue(i).Position = [((app.alphaValue(1).Position(1)) + 1), ((app.alphaValue(1).Position(2)) + 1) - 20*(i-1), app.alphaValue(1).Position(3), app.alphaValue(1).Position(4)];
                            app.dValue(i).Position = [((app.dValue(1).Position(1)) + 1), ((app.dValue(1).Position(2)) + 1) - 20*(i-1), app.dValue(1).Position(3), app.dValue(1).Position(4)];
                            app.thetaValue(i).Position = [((app.thetaValue(1).Position(1)) + 1), ((app.thetaValue(1).Position(2)) + 1) - 20*(i-1), app.thetaValue(1).Position(3), app.thetaValue(1).Position(4)]; 
                            app.rpValue(i).Position = [((app.rpValue(1).Position(1))), ((app.rpValue(1).Position(2))) - 20*(i-1), app.rpValue(1).Position(3), app.rpValue(1).Position(4)];
                            app.mJointValue(i).Position = [((app.mJointValue(1).Position(1))), ((app.mJointValue(1).Position(2))) - 20*(i-1), app.mJointValue(1).Position(3), app.mJointValue(1).Position(4)];
                            app.IJointValue(i).Position = [((app.IJointValue(1).Position(1))), ((app.IJointValue(1).Position(2))) - 20*(i-1), app.IJointValue(1).Position(3), app.IJointValue(1).Position(4)];
                            app.krValue(i).Position = [((app.krValue(1).Position(1))), ((app.krValue(1).Position(2))) - 20*(i-1), app.krValue(1).Position(3), app.krValue(1).Position(4)];
                            app.sfValue(i).Position = [((app.sfValue(1).Position(1))), ((app.sfValue(1).Position(2))) - 20*(i-1), app.sfValue(1).Position(3), app.sfValue(1).Position(4)];
                            app.vfValue(i).Position = [((app.vfValue(1).Position(1))), ((app.vfValue(1).Position(2))) - 20*(i-1), app.vfValue(1).Position(3), app.vfValue(1).Position(4)];                    
                            app.mLinkValue(i).Position = [((app.mLinkValue(1).Position(1)) + 1), ((app.mLinkValue(1).Position(2)) + 1) - 20*(i-1), app.mLinkValue(1).Position(3), app.mLinkValue(1).Position(4)];
                            app.ILinkValue(i).Position = [((app.ILinkValue(1).Position(1)) + 1), ((app.ILinkValue(1).Position(2)) + 1) - 20*(i-1), app.ILinkValue(1).Position(3), app.ILinkValue(1).Position(4)];

                            cm_a = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'a'));
                            app.aMenu(i) = uimenu(cm_a, 'Text', '');
                            app.aValue(i).ContextMenu = cm_a;
                            app.cms_a{i} = cm_a;
        
                            cm_alpha = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'alpha'));
                            app.alphaMenu(i) = uimenu(cm_alpha, 'Text', '');
                            app.alphaValue(i).ContextMenu = cm_alpha;
                            app.cms_alpha{i} = cm_alpha;
        
                            cm_d = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'd'));
                            app.dMenu(i) = uimenu(cm_d, 'Text', '');
                            app.dValue(i).ContextMenu = cm_d;
                            app.cms_d{i} = cm_d;
        
                            cm_theta = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'theta'));
                            app.thetaMenu(i) = uimenu(cm_theta, 'Text', '');
                            app.thetaValue(i).ContextMenu = cm_theta;
                            app.cms_theta{i} = cm_theta;
        
                            cm_rp = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'rp'));
                            app.rpMenu(i) = uimenu(cm_rp, 'Text', '');
                            app.rpValue(i).ContextMenu = cm_rp;
                            app.cms_rp{i} = cm_rp;
        
                            cm_mJoint = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'mJoint'));
                            app.mJointMenu(i) = uimenu(cm_mJoint, 'Text', '');
                            app.mJointValue(i).ContextMenu = cm_mJoint;
                            app.cms_mJoint{i} = cm_mJoint;
        
                            cm_IJoint = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'IJoint'));
                            app.IJointMenu(i) = uimenu(cm_IJoint, 'Text', '');
                            app.IJointValue(i).ContextMenu = cm_IJoint;
                            app.cms_IJoint{i} = cm_IJoint;
        
                            cm_kr = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'kr'));
                            app.krMenu(i) = uimenu(cm_kr, 'Text', '');
                            app.krValue(i).ContextMenu = cm_kr;
                            app.cms_kr{i} = cm_kr;
        
                            cm_sf = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'sf'));
                            app.sfMenu(i) = uimenu(cm_sf, 'Text', '');                    
                            app.sfValue(i).ContextMenu = cm_sf;
                            app.cms_sf{i} = cm_sf;
        
                            cm_vf = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'vf'));
                            app.vfMenu(i) = uimenu(cm_vf, 'Text', '');                    
                            app.vfValue(i).ContextMenu = cm_vf; 
                            app.cms_vf{i} = cm_vf;

                            cm_mLink = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'mLink'));
                            app.mLinkMenu(i) = uimenu(cm_mLink, 'Text', '');
                            app.mLinkValue(i).ContextMenu = cm_mLink;
                            app.cms_mLink{i} = cm_mLink;
    
                            cm_ILink = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'ILink'));
                            app.ILinkMenu(i) = uimenu(cm_ILink, 'Text', '');
                            app.ILinkValue(i).ContextMenu = cm_ILink;
                            app.cms_ILink{i} = cm_ILink;
        
                            if i <= length(app.aValues)
                                app.aValue(i).Value = app.aValues(i);
                            else
                                app.aValue(i).Value = 0;
                                app.aValues(i) = 0;
                            end
                            if i <= length(app.alphaValues)
                                app.alphaValue(i).Value = app.alphaValues(i);
                            else
                                app.alphaValue(i).Value = 0;
                                app.alphaValues(i) = 0;
                            end
                            if i <= length(app.dValues)
                                app.dValue(i).Value = app.dValues(i);
                            else
                                app.dValue(i).Value = 0;
                                app.dValues(i) = 0;
                            end
                            if i <= length(app.thetaValues)
                                app.thetaValue(i).Value = app.thetaValues(i);
                            else
                                app.thetaValue(i).Value = 0;
                                app.thetaValues(i) = 0;
                            end
                            if i <= length(app.rpValues) && ~isempty(app.rpValues{i})
                                fprintf('rpValues{%d} = "%s"\n', i, app.rpValues{i});
                                app.rpValue(i).Value = app.rpValues{i};
                            else
                                app.rpValue(i).Value = 'Revolut';
                                app.rpValues{i} = 'Revolut';
                            end
                            if i <= length(app.mJointValues)
                                app.mJointValue(i).Value = app.mJointValues(i);
                            else
                                app.mJointValue(i).Value = 0;
                                app.mJointValues(i) = 0;
                            end
                            if i <= length(app.IJointValues)
                                app.IJointValue(i).Value = app.IJointValues(i);
                            else
                                app.IJointValue(i).Value = 0;
                                app.IJointValues(i) = 0;
                            end
                            if i <= length(app.krValues)
                                app.krValue(i).Value = app.krValues(i);
                            else
                                app.krValue(i).Value = 0;
                                app.krValues(i) = 0;
                            end
                            if i <= length(app.sfValues)
                                app.sfValue(i).Value = app.sfValues(i);
                            else
                                app.sfValue(i).Value = 0;
                                app.sfValues(i) = 0;
                            end
                            if i <= length(app.vfValues)
                                app.vfValue(i).Value = app.vfValues(i);
                            else
                                app.vfValue(i).Value = 0;
                                app.vfValues(i) = 0;
                            end  
                            if i <= length(app.mLinkValues)
                                app.mLinkValue(i).Value = app.mLinkValues(i);
                            else
                                app.mLinkValue(i).Value = 0;
                                app.mLinkValues(i) = 0;
                            end
                            if i <= length(app.ILinkValues)
                                app.ILinkValue(i).Value = app.ILinkValues(i);
                            else
                                app.ILinkValue(i).Value = 0;
                                app.ILinkValues(i) = 0;
                            end
                        end
                    end
                %else
                    fprintf('same\n')
                %end
                app.jointCountPrevious = app.jointCount;
                %app.menuPrevious = 2;
            end
            if app.MainMenus.SelectedObject == app.IFCIButton

                a = [app.jointCount; app.jointCountPrevious];

                fprintf('%d joints to %d\n', app.jointCountPrevious, app.jointCount)

                %if app.menuPrevious == 1
                    for i = (min(a)+1):max(a)
                        fprintf('%d\n', i)
                        if app.jointCountPrevious > app.jointCount
                            fprintf('del\n')
                            delete(app.jointNumber(i));
                            delete(app.aValue(i));
                            delete(app.alphaValue(i));
                            delete(app.dValue(i));
                            delete(app.thetaValue(i));
                            delete(app.rpValue(i));
    
                            delete(app.mJointValue(i));
                            delete(app.IJointValue(i));
                            delete(app.krValue(i));
                            delete(app.sfValue(i));
                            delete(app.vfValue(i));
                            delete(app.linkNumber(i));
                            delete(app.mLinkValue(i));
                            delete(app.ILinkValue(i));
    
                            delete(app.cms_a{i});
                            delete(app.cms_alpha{i});
                            delete(app.cms_d{i});
                            delete(app.cms_theta{i});
                            delete(app.cms_rp{i});
                            delete(app.cms_mJoint{i});
                            delete(app.cms_IJoint{i});
                            delete(app.cms_kr{i});
                            delete(app.cms_sf{i});
                            delete(app.cms_vf{i});
                            delete(app.cms_mLink{i});
                            delete(app.cms_ILink{i});
    
                        else
                            fprintf('add\n')
                            app.jointNumber(i) = uilabel(app.UIFigure, 'Text', num2str(i));
                            app.aValue(i) = uieditfield(app.UIFigure, 'numeric');
                            app.alphaValue(i) = uieditfield(app.UIFigure, 'numeric');
                            app.dValue(i) = uieditfield(app.UIFigure, 'numeric', 'Editable', 'on');
                            app.thetaValue(i) = uieditfield(app.UIFigure, 'numeric', 'Editable', 'on');
                            app.rpValue(i) = uidropdown(app.UIFigure, 'Items', {'Revolut', 'Prismatic'}, 'Value', 'Revolut');
                            app.mJointValue(i) = uieditfield(app.UIFigure, 'numeric');
                            app.IJointValue(i) = uieditfield(app.UIFigure, 'numeric');
                            app.krValue(i) = uieditfield(app.UIFigure, 'numeric');
                            app.sfValue(i) = uieditfield(app.UIFigure, 'numeric');
                            app.vfValue(i) = uieditfield(app.UIFigure, 'numeric');
                            app.linkNumber(i) = uilabel(app.UIFigure, 'Text', num2str(i));
                            app.mLinkValue(i) = uieditfield(app.UIFigure, 'numeric');
                            app.ILinkValue(i) = uieditfield(app.UIFigure, 'numeric');
        
                            app.aValue(i).ValueChangedFcn = createCallbackFcn(app, @aValueValueChanged, true);
                            app.alphaValue(i).ValueChangedFcn = createCallbackFcn(app, @alphaValueValueChanged, true);
                            app.dValue(i).ValueChangedFcn = createCallbackFcn(app, @dValueValueChanged, true);
                            app.thetaValue(i).ValueChangedFcn = createCallbackFcn(app, @thetaValueValueChanged, true);
                            app.rpValue(i).ValueChangedFcn = createCallbackFcn(app, @rpValueValueChanged, true);
                            app.mJointValue(i).ValueChangedFcn = createCallbackFcn(app, @mJointValueValueChanged, true);
                            app.IJointValue(i).ValueChangedFcn = createCallbackFcn(app, @IJointValueValueChanged, true);
                            app.krValue(i).ValueChangedFcn = createCallbackFcn(app, @krValueValueChanged, true);
                            app.sfValue(i).ValueChangedFcn = createCallbackFcn(app, @sfValueValueChanged, true);
                            app.vfValue(i).ValueChangedFcn = createCallbackFcn(app, @vfValueValueChanged, true);
                            app.mLinkValue(i).ValueChangedFcn = createCallbackFcn(app, @mLinkValueValueChanged, true);
                            app.ILinkValue(i).ValueChangedFcn = createCallbackFcn(app, @ILinkValueValueChanged, true);
        
                            if i == 10
                                app.jointNumber(i).Position = [((app.jointNumber(1).Position(1)) - 3), ((app.jointNumber(1).Position(2)) + 1) - 20*(i-1), app.jointNumber(1).Position(3), app.jointNumber(1).Position(4)];
                                app.linkNumber(i).Position = [((app.linkNumber(1).Position(1)) - 3), ((app.linkNumber(1).Position(2)) + 1) - 20*(i-1), app.linkNumber(1).Position(3), app.linkNumber(1).Position(4)];
                            else
                                app.jointNumber(i).Position = [((app.jointNumber(1).Position(1)) + 1), ((app.jointNumber(1).Position(2)) + 1) - 20*(i-1), app.jointNumber(1).Position(3), app.jointNumber(1).Position(4)];
                                app.linkNumber(i).Position = [((app.linkNumber(1).Position(1)) + 1), ((app.linkNumber(1).Position(2)) + 1) - 20*(i-1), app.linkNumber(1).Position(3), app.linkNumber(1).Position(4)];
                            end
                            app.aValue(i).Position = [((app.aValue(1).Position(1)) + 1), ((app.aValue(1).Position(2)) + 1) - 20*(i-1), app.aValue(1).Position(3), app.aValue(1).Position(4)];
                            app.alphaValue(i).Position = [((app.alphaValue(1).Position(1)) + 1), ((app.alphaValue(1).Position(2)) + 1) - 20*(i-1), app.alphaValue(1).Position(3), app.alphaValue(1).Position(4)];
                            app.dValue(i).Position = [((app.dValue(1).Position(1)) + 1), ((app.dValue(1).Position(2)) + 1) - 20*(i-1), app.dValue(1).Position(3), app.dValue(1).Position(4)];
                            app.thetaValue(i).Position = [((app.thetaValue(1).Position(1)) + 1), ((app.thetaValue(1).Position(2)) + 1) - 20*(i-1), app.thetaValue(1).Position(3), app.thetaValue(1).Position(4)]; 
                            app.rpValue(i).Position = [((app.rpValue(1).Position(1))), ((app.rpValue(1).Position(2))) - 20*(i-1), app.rpValue(1).Position(3), app.rpValue(1).Position(4)];
                            app.mJointValue(i).Position = [((app.mJointValue(1).Position(1))), ((app.mJointValue(1).Position(2))) - 20*(i-1), app.mJointValue(1).Position(3), app.mJointValue(1).Position(4)];
                            app.IJointValue(i).Position = [((app.IJointValue(1).Position(1))), ((app.IJointValue(1).Position(2))) - 20*(i-1), app.IJointValue(1).Position(3), app.IJointValue(1).Position(4)];
                            app.krValue(i).Position = [((app.krValue(1).Position(1))), ((app.krValue(1).Position(2))) - 20*(i-1), app.krValue(1).Position(3), app.krValue(1).Position(4)];
                            app.sfValue(i).Position = [((app.sfValue(1).Position(1))), ((app.sfValue(1).Position(2))) - 20*(i-1), app.sfValue(1).Position(3), app.sfValue(1).Position(4)];
                            app.vfValue(i).Position = [((app.vfValue(1).Position(1))), ((app.vfValue(1).Position(2))) - 20*(i-1), app.vfValue(1).Position(3), app.vfValue(1).Position(4)];                    
                            app.mLinkValue(i).Position = [((app.mLinkValue(1).Position(1)) + 1), ((app.mLinkValue(1).Position(2)) + 1) - 20*(i-1), app.mLinkValue(1).Position(3), app.mLinkValue(1).Position(4)];
                            app.ILinkValue(i).Position = [((app.ILinkValue(1).Position(1)) + 1), ((app.ILinkValue(1).Position(2)) + 1) - 20*(i-1), app.ILinkValue(1).Position(3), app.ILinkValue(1).Position(4)];

                            cm_a = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'a'));
                            app.aMenu(i) = uimenu(cm_a, 'Text', '');
                            app.aValue(i).ContextMenu = cm_a;
                            app.cms_a{i} = cm_a;
        
                            cm_alpha = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'alpha'));
                            app.alphaMenu(i) = uimenu(cm_alpha, 'Text', '');
                            app.alphaValue(i).ContextMenu = cm_alpha;
                            app.cms_alpha{i} = cm_alpha;
        
                            cm_d = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'd'));
                            app.dMenu(i) = uimenu(cm_d, 'Text', '');
                            app.dValue(i).ContextMenu = cm_d;
                            app.cms_d{i} = cm_d;
        
                            cm_theta = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'theta'));
                            app.thetaMenu(i) = uimenu(cm_theta, 'Text', '');
                            app.thetaValue(i).ContextMenu = cm_theta;
                            app.cms_theta{i} = cm_theta;
        
                            cm_rp = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'rp'));
                            app.rpMenu(i) = uimenu(cm_rp, 'Text', '');
                            app.rpValue(i).ContextMenu = cm_rp;
                            app.cms_rp{i} = cm_rp;
        
                            cm_mJoint = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'mJoint'));
                            app.mJointMenu(i) = uimenu(cm_mJoint, 'Text', '');
                            app.mJointValue(i).ContextMenu = cm_mJoint;
                            app.cms_mJoint{i} = cm_mJoint;
        
                            cm_IJoint = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'IJoint'));
                            app.IJointMenu(i) = uimenu(cm_IJoint, 'Text', '');
                            app.IJointValue(i).ContextMenu = cm_IJoint;
                            app.cms_IJoint{i} = cm_IJoint;
        
                            cm_kr = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'kr'));
                            app.krMenu(i) = uimenu(cm_kr, 'Text', '');
                            app.krValue(i).ContextMenu = cm_kr;
                            app.cms_kr{i} = cm_kr;
        
                            cm_sf = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'sf'));
                            app.sfMenu(i) = uimenu(cm_sf, 'Text', '');                    
                            app.sfValue(i).ContextMenu = cm_sf;
                            app.cms_sf{i} = cm_sf;
        
                            cm_vf = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'vf'));
                            app.vfMenu(i) = uimenu(cm_vf, 'Text', '');                    
                            app.vfValue(i).ContextMenu = cm_vf; 
                            app.cms_vf{i} = cm_vf;

                            cm_mLink = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'mLink'));
                            app.mLinkMenu(i) = uimenu(cm_mLink, 'Text', '');
                            app.mLinkValue(i).ContextMenu = cm_mLink;
                            app.cms_mLink{i} = cm_mLink;
    
                            cm_ILink = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, i, 'ILink'));
                            app.ILinkMenu(i) = uimenu(cm_ILink, 'Text', '');
                            app.ILinkValue(i).ContextMenu = cm_ILink;
                            app.cms_ILink{i} = cm_ILink;
        
                            if i <= length(app.aValues)
                                app.aValue(i).Value = app.aValues(i);
                            else
                                app.aValue(i).Value = 0;
                                app.aValues(i) = 0;
                            end
                            if i <= length(app.alphaValues)
                                app.alphaValue(i).Value = app.alphaValues(i);
                            else
                                app.alphaValue(i).Value = 0;
                                app.alphaValues(i) = 0;
                            end
                            if i <= length(app.dValues)
                                app.dValue(i).Value = app.dValues(i);
                            else
                                app.dValue(i).Value = 0;
                                app.dValues(i) = 0;
                            end
                            if i <= length(app.thetaValues)
                                app.thetaValue(i).Value = app.thetaValues(i);
                            else
                                app.thetaValue(i).Value = 0;
                                app.thetaValues(i) = 0;
                            end
                            if i <= length(app.rpValues) && ~isempty(app.rpValues{i})
                                fprintf('rpValues{%d} = "%s"\n', i, app.rpValues{i});
                                app.rpValue(i).Value = app.rpValues{i};
                            else
                                app.rpValue(i).Value = 'Revolut';
                                app.rpValues{i} = 'Revolut';
                            end
                            if i <= length(app.mJointValues)
                                app.mJointValue(i).Value = app.mJointValues(i);
                            else
                                app.mJointValue(i).Value = 0;
                                app.mJointValues(i) = 0;
                            end
                            if i <= length(app.IJointValues)
                                app.IJointValue(i).Value = app.IJointValues(i);
                            else
                                app.IJointValue(i).Value = 0;
                                app.IJointValues(i) = 0;
                            end
                            if i <= length(app.krValues)
                                app.krValue(i).Value = app.krValues(i);
                            else
                                app.krValue(i).Value = 0;
                                app.krValues(i) = 0;
                            end
                            if i <= length(app.sfValues)
                                app.sfValue(i).Value = app.sfValues(i);
                            else
                                app.sfValue(i).Value = 0;
                                app.sfValues(i) = 0;
                            end
                            if i <= length(app.vfValues)
                                app.vfValue(i).Value = app.vfValues(i);
                            else
                                app.vfValue(i).Value = 0;
                                app.vfValues(i) = 0;
                            end
                            if i <= length(app.mLinkValues)
                                app.mLinkValue(i).Value = app.mLinkValues(i);
                            else
                                app.mLinkValue(i).Value = 0;
                                app.mLinkValues(i) = 0;
                            end
                            if i <= length(app.ILinkValues)
                                app.ILinkValue(i).Value = app.ILinkValues(i);
                            else
                                app.ILinkValue(i).Value = 0;
                                app.ILinkValues(i) = 0;
                            end
                        end
                    end
                %else
                    fprintf('same\n')
                %end
                app.jointCountPrevious = app.jointCount;
                %app.menuPrevious = 3;
            end
        end

        function cmInitialize(app)
            cm_a = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, 1, 'a'));
            app.aMenu(1) = uimenu(cm_a, 'Text', '');
            app.aValue(1).ContextMenu = cm_a;
            app.cms_a{1} = cm_a;
    
            cm_alpha = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, 1, 'alpha'));
            app.alphaMenu(1) = uimenu(cm_alpha, 'Text', '');
            app.alphaValue(1).ContextMenu = cm_alpha;
            app.cms_alpha{1} = cm_alpha;
    
            cm_d = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, 1, 'd'));
            app.dMenu(1) = uimenu(cm_d, 'Text', '');
            app.dValue(1).ContextMenu = cm_d;
            app.cms_d{1} = cm_d;
    
            cm_theta = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, 1, 'theta'));
            app.thetaMenu(1) = uimenu(cm_theta, 'Text', '');
            app.thetaValue(1).ContextMenu = cm_theta;
            app.cms_theta{1} = cm_theta;
    
            cm_rp = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, 1, 'rp'));
            app.rpMenu(1) = uimenu(cm_rp, 'Text', '');
            app.rpValue(1).ContextMenu = cm_rp;
            app.cms_rp{1} = cm_rp;
            
            if app.MainMenus.SelectedObject == app.DynamicsButton
                cm_icDot = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, 1, 'icDot'));
                app.icDotMenu(1) = uimenu(cm_icDot, 'Text', '');
                app.icDotValue(1).ContextMenu = cm_icDot;
                app.cms_icDot{1} = cm_icDot;
        
                cm_icDoubleDot = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, 1, 'icDoubleDot'));
                app.icDoubleDotMenu(1) = uimenu(cm_icDoubleDot, 'Text', '');
                app.icDoubleDotValue(1).ContextMenu = cm_icDoubleDot;
                app.cms_icDoubleDot{1} = cm_icDoubleDot;
        
                cm_dEquation = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, 1, 'dEquation'));
                app.dEquationMenu(1) = uimenu(cm_dEquation, 'Text', '');
                app.dEquation(1).ContextMenu = cm_dEquation;
                app.cms_dEquation{1} = cm_dEquation;
            end
            if app.MainMenus.SelectedObject == app.IFCCButton || app.MainMenus.SelectedObject == app.IFCIButton
                cm_mJoint = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, 1, 'mJoint'));
                app.mJointMenu(1) = uimenu(cm_mJoint, 'Text', '');
                app.mJointValue(1).ContextMenu = cm_mJoint;
                app.cms_mJoint{1} = cm_mJoint;
        
                cm_IJoint = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, 1, 'IJoint'));
                app.IJointMenu(1) = uimenu(cm_IJoint, 'Text', '');
                app.IJointValue(1).ContextMenu = cm_IJoint;
                app.cms_IJoint{1} = cm_IJoint;
        
                cm_kr = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, 1, 'kr'));
                app.krMenu(1) = uimenu(cm_kr, 'Text', '');
                app.krValue(1).ContextMenu = cm_kr;
                app.cms_kr{1} = cm_kr;
        
                cm_sf = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, 1, 'sf'));
                app.sfMenu(1) = uimenu(cm_sf, 'Text', '');                    
                app.sfValue(1).ContextMenu = cm_sf;
                app.cms_sf{1} = cm_sf;
        
                cm_vf = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, 1, 'vf'));
                app.vfMenu(1) = uimenu(cm_vf, 'Text', '');                    
                app.vfValue(1).ContextMenu = cm_vf;
                app.cms_vf{1} = cm_vf;

                cm_mLink = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, 1, 'mLink'));
                app.mLinkMenu(1) = uimenu(cm_mLink, 'Text', '');
                app.mLinkValue(1).ContextMenu = cm_mLink;
                app.cms_mLink{1} = cm_mLink;
    
                cm_ILink = uicontextmenu(app.UIFigure, 'ContextMenuOpeningFcn', @(src, event) updateContextMenu(app, 1, 'ILink'));
                app.ILinkMenu(1) = uimenu(cm_ILink, 'Text', '');
                app.ILinkValue(1).ContextMenu = cm_ILink;
                app.cms_ILink{1} = cm_ILink;
            end
        end

        function cmDeleteIndex1(app)
            types = {'cms_a', 'cms_alpha', 'cms_d', 'cms_theta', 'cms_rp', 'cms_icDot', 'cms_icDoubleDot', 'cms_dEquation', 'cms_mJoint', 'cms_IJoint', 'cms_kr', 'cms_sf', 'cms_vf', 'cms_mLink', 'cms_ILink'};
            for t = 1:length(types)
                field = types{t};
                if ~isempty(app.(field)) && length(app.(field)) >= 1 && ~isempty(app.(field){1}) && isvalid(app.(field){1})
                    delete(app.(field){1});
                    app.(field){1} = [];
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.UIFigure.Resize = 'off';
        app.rpValues{1} = 'Revolut';

        cmInitialize(app);
        end

        % Value changed function: ofJointsSpinner
        function ofJointsSpinnerValueChanged(app, event)
            app.jointCount = app.ofJointsSpinner.Value;
            
            if app.jointCount < 1
                app.ofJointsSpinner.Value = 1;
                app.jointCount = app.ofJointsSpinner.Value;
            end

            if app.jointCount > 10
                app.ofJointsSpinner.Value = 10;
                app.jointCount = app.ofJointsSpinner.Value;
            end

            updateMainMenu(app);
        end

        % Value changed function: aValue
        function aValueValueChanged(app, event)
            app.aValues(find(app.aValue == event.Source)) = event.Value;
            fprintf('Box number %d was changed to %f\n', find(app.aValue == event.Source), event.Value);
        end

        % Value changed function: alphaValue
        function alphaValueValueChanged(app, event)
            app.alphaValues(find(app.alphaValue == event.Source)) = event.Value;
            fprintf('Box number %d was changed to %f\n', find(app.alphaValue == event.Source), event.Value);
        end

        % Value changed function: dValue
        function dValueValueChanged(app, event)
            app.dValues(find(app.dValue == event.Source)) = event.Value;
            fprintf('Box number %d was changed to %f\n', find(app.dValue == event.Source), event.Value);
        end

        % Value changed function: thetaValue
        function thetaValueValueChanged(app, event)
            app.thetaValues(find(app.thetaValue == event.Source)) = event.Value;
            fprintf('Box number %d was changed to %f\n', find(app.thetaValue == event.Source), event.Value);
        end

        % Value changed function: rpValue
        function rpValueValueChanged(app, event)
            app.rpValues{find(app.rpValue == event.Source)} = event.Value;
            fprintf('Box number %d was changed to %s\n', find(app.rpValue == event.Source), event.Value);
        end

        % Value changed function: mJointValue
        function mJointValueValueChanged(app, event)
            app.mJointValues(find(app.mJointValue == event.Source)) = event.Value;
            fprintf('Box number %d was changed to %f\n', find(app.mJointValue == event.Source), event.Value);
        end

        % Value changed function: sfValue
        function sfValueValueChanged(app, event)
            app.sfValues(find(app.sfValue == event.Source)) = event.Value;
            fprintf('Box number %d was changed to %f\n', find(app.sfValue == event.Source), event.Value);
        end

        % Value changed function: vfValue
        function vfValueValueChanged(app, event)
            app.vfValues(find(app.vfValue == event.Source)) = event.Value;
            fprintf('Box number %d was changed to %f\n', find(app.vfValue == event.Source), event.Value);
        end

        % Value changed function: krValue
        function krValueValueChanged(app, event)
            app.krValues(find(app.krValue == event.Source)) = event.Value;
            fprintf('Box number %d was changed to %f\n', find(app.krValue == event.Source), event.Value);
        end

        % Selection changed function: MainMenus
        function MainMenusSelectionChanged(app, event)
            if app.MainMenus.SelectedObject == app.DynamicsButton
                app.jointCountPrevious = 1;
                app.menuCurrent = 1;

                a = [app.jointCount; app.jointCountPrevious];
                
                app.mJointValue(1).Visible = 'off';
                app.IJointValue(1).Visible = 'off';
                app.krValue(1).Visible = 'off';
                app.sfValue(1).Visible = 'off';
                app.vfValue(1).Visible = 'off';
                app.linkNumber(1).Visible = 'off';
                app.mLinkValue(1).Visible = 'off';
                app.ILinkValue(1).Visible = 'off';

                if ~isempty(app.cms_mJoint) && ~isempty(app.cms_mJoint{1}) && isvalid(app.cms_mJoint{1})
                    delete(app.cms_mJoint{1}); app.cms_mJoint{1} = [];
                end
                if ~isempty(app.cms_IJoint) && ~isempty(app.cms_IJoint{1}) && isvalid(app.cms_IJoint{1})
                    delete(app.cms_IJoint{1}); app.cms_IJoint{1} = [];
                end
                if ~isempty(app.cms_kr) && ~isempty(app.cms_kr{1}) && isvalid(app.cms_kr{1})
                    delete(app.cms_kr{1}); app.cms_kr{1} = [];
                end
                if ~isempty(app.cms_sf) && ~isempty(app.cms_sf{1}) && isvalid(app.cms_sf{1})
                    delete(app.cms_sf{1}); app.cms_sf{1} = [];
                end
                if ~isempty(app.cms_vf) && ~isempty(app.cms_vf{1}) && isvalid(app.cms_vf{1})
                    delete(app.cms_vf{1}); app.cms_vf{1} = [];
                end
                if ~isempty(app.cms_mLink) && ~isempty(app.cms_mLink{1}) && isvalid(app.cms_mLink{1})
                    delete(app.cms_mLink{1}); app.cms_mLink{1} = [];
                end
                if ~isempty(app.cms_ILink) && ~isempty(app.cms_ILink{1}) && isvalid(app.cms_ILink{1})
                    delete(app.cms_ILink{1}); app.cms_ILink{1} = [];
                end

                app.mJointLabel.Visible = 'off';
                app.IJointLabel.Visible = 'off';
                app.krLabel.Visible = 'off';
                app.sfLabel.Visible = 'off';
                app.vfLabel.Visible = 'off';
                app.mLinkLabel.Visible = 'off';
                app.ILinkLabel.Visible = 'off';
                app.LinksTableLabel.Visible = 'off';

                app.icDotValue(1).Visible = 'on';
                app.icDoubleDotValue(1).Visible = 'on';
                app.dEquation(1).Visible = 'on';
                app.icDotLabel.Visible = 'on';
                app.icDoubleDotLabel.Visible = 'on';
                app.dEquationLabel.Visible = 'on';

                for i = (min(a)+1):max(a)
                    fprintf('i = %d\n',i);
                    %fprintf('Joint Count = %d\n',app.jointCount);
                    %fprintf('Previous Joint Count = %d\n',app.jointCountPrevious);

                    delete(app.jointNumber(i));
                    delete(app.aValue(i));
                    delete(app.alphaValue(i));
                    delete(app.dValue(i));
                    delete(app.thetaValue(i));
                    delete(app.rpValue(i));
                    delete(app.mJointValue(i));
                    delete(app.IJointValue(i));
                    delete(app.krValue(i));
                    delete(app.sfValue(i));
                    delete(app.vfValue(i));
                    delete(app.linkNumber(i));
                    delete(app.mLinkValue(i));
                    delete(app.ILinkValue(i));

                    delete(app.cms_a{i});
                    delete(app.cms_alpha{i});
                    delete(app.cms_d{i});
                    delete(app.cms_theta{i});
                    delete(app.cms_rp{i});
                    delete(app.cms_mJoint{i});
                    delete(app.cms_IJoint{i});
                    delete(app.cms_kr{i});
                    delete(app.cms_sf{i});
                    delete(app.cms_vf{i});
                    delete(app.cms_mLink{i});
                    delete(app.cms_ILink{i});
                end
                %fprintf('pre %d now %d\n', app.menuPrevious, app.menuCurrent)
                cmDeleteIndex1(app);
                cmInitialize(app);
    
                updateMainMenu(app);
                app.menuPrevious = 1;
            end
            if app.MainMenus.SelectedObject == app.IFCCButton
                if app.menuPrevious == 1
                    app.jointCountPrevious = 1;
                    app.menuCurrent = 2;

                    a = [app.jointCount; app.jointCountPrevious];

                    app.icDotValue(1).Visible = 'off';
                    app.icDoubleDotValue(1).Visible = 'off';
                    app.dEquation(1).Visible = 'off';
    
                    if ~isempty(app.cms_icDot) && ~isempty(app.cms_icDot{1}) && isvalid(app.cms_icDot{1})
                        delete(app.cms_icDot{1}); app.cms_icDot{1} = [];
                    end
                    if ~isempty(app.cms_icDoubleDot) && ~isempty(app.cms_icDoubleDot{1}) && isvalid(app.cms_icDoubleDot{1})
                        delete(app.cms_icDoubleDot{1}); app.cms_icDoubleDot{1} = [];
                    end
                    if ~isempty(app.cms_dEquation) && ~isempty(app.cms_dEquation{1}) && isvalid(app.cms_dEquation{1})
                        delete(app.cms_dEquation{1}); app.cms_dEquation{1} = [];
                    end
    
                    app.icDotLabel.Visible = 'off';
                    app.icDoubleDotLabel.Visible = 'off';
                    app.dEquationLabel.Visible = 'off';
    
                    app.mJointValue(1).Visible = 'on';
                    app.IJointValue(1).Visible = 'on';
                    app.krValue(1).Visible = 'on';
                    app.sfValue(1).Visible = 'on';
                    app.vfValue(1).Visible = 'on';
                    app.mLinkValue(1).Visible = 'on';
                    app.ILinkValue(1).Visible = 'on';
                    app.mJointLabel.Visible = 'on';
                    app.IJointLabel.Visible = 'on';
                    app.krLabel.Visible = 'on';
                    app.sfLabel.Visible = 'on';
                    app.vfLabel.Visible = 'on';
                    app.LinksTableLabel.Visible = 'on';
                    app.linkNumber(1).Visible = 'on';
                    app.mLinkLabel.Visible = 'on';
                    app.ILinkLabel.Visible = 'on';
    
                    for i = (min(a)+1):max(a)
                        delete(app.jointNumber(i));
                        delete(app.aValue(i));
                        delete(app.alphaValue(i));
                        delete(app.dValue(i));
                        delete(app.thetaValue(i));
                        delete(app.rpValue(i));
                        delete(app.icDotValue(i));
                        delete(app.icDoubleDotValue(i));
                        delete(app.dEquation(i));
    
                        delete(app.cms_a{i});
                        delete(app.cms_alpha{i});
                        delete(app.cms_d{i});
                        delete(app.cms_theta{i});
                        delete(app.cms_rp{i});
                        delete(app.cms_icDot{i});
                        delete(app.cms_icDoubleDot{i});
                        delete(app.cms_dEquation{i});
                    end
                else
                    fprintf('same in menu\n')
                end
                cmDeleteIndex1(app);
                cmInitialize(app);
    
                updateMainMenu(app);
                app.menuPrevious = 2;
            end
            if app.MainMenus.SelectedObject == app.IFCIButton
                if app.menuPrevious == 1
                    app.jointCountPrevious = 1;
                    app.menuCurrent = 3;

                    a = [app.jointCount; app.jointCountPrevious];

                    app.icDotValue(1).Visible = 'off';
                    app.icDoubleDotValue(1).Visible = 'off';
                    app.dEquation(1).Visible = 'off';
    
                    if ~isempty(app.cms_icDot) && ~isempty(app.cms_icDot{1}) && isvalid(app.cms_icDot{1})
                        delete(app.cms_icDot{1}); app.cms_icDot{1} = [];
                    end
                    if ~isempty(app.cms_icDoubleDot) && ~isempty(app.cms_icDoubleDot{1}) && isvalid(app.cms_icDoubleDot{1})
                        delete(app.cms_icDoubleDot{1}); app.cms_icDoubleDot{1} = [];
                    end
                    if ~isempty(app.cms_dEquation) && ~isempty(app.cms_dEquation{1}) && isvalid(app.cms_dEquation{1})
                        delete(app.cms_dEquation{1}); app.cms_dEquation{1} = [];
                    end
    
                    app.icDotLabel.Visible = 'off';
                    app.icDoubleDotLabel.Visible = 'off';
                    app.dEquationLabel.Visible = 'off';
    
                    app.mJointValue(1).Visible = 'on';
                    app.IJointValue(1).Visible = 'on';
                    app.krValue(1).Visible = 'on';
                    app.sfValue(1).Visible = 'on';
                    app.vfValue(1).Visible = 'on';
                    app.mLinkValue(1).Visible = 'on';
                    app.ILinkValue(1).Visible = 'on';
                    app.mJointLabel.Visible = 'on';
                    app.IJointLabel.Visible = 'on';
                    app.krLabel.Visible = 'on';
                    app.sfLabel.Visible = 'on';
                    app.vfLabel.Visible = 'on';
                    app.LinksTableLabel.Visible = 'on';
                    app.linkNumber(1).Visible = 'on';
                    app.mLinkLabel.Visible = 'on';
                    app.ILinkLabel.Visible = 'on';
    
                    for i = (min(a)+1):max(a)
                        delete(app.jointNumber(i));
                        delete(app.aValue(i));
                        delete(app.alphaValue(i));
                        delete(app.dValue(i));
                        delete(app.thetaValue(i));
                        delete(app.rpValue(i));
                        delete(app.icDotValue(i));
                        delete(app.icDoubleDotValue(i));
                        delete(app.dEquation(i));
    
                        delete(app.cms_a{i});
                        delete(app.cms_alpha{i});
                        delete(app.cms_d{i});
                        delete(app.cms_theta{i});
                        delete(app.cms_rp{i});
                        delete(app.cms_icDot{i});
                        delete(app.cms_icDoubleDot{i});
                        delete(app.cms_dEquation{i});
                    end
                else
                    fprintf('same in menu\n')
                end
                cmDeleteIndex1(app);
                cmInitialize(app);
    
                updateMainMenu(app);
                app.menuPrevious = 3;
            end
            %{
            fprintf('pre %d now %d\n', app.menuPrevious, app.menuCurrent)

            cmDeleteIndex1(app);
            cmInitialize(app);

            updateMainMenu(app);

            %}
        end

        % Value changed function: icDotValue
        function icDotValueValueChanged(app, event)
            app.icDotValues(find(app.icDotValue == event.Source)) = event.Value;
            fprintf('Box number %d was changed to %f\n', find(app.icDotValue == event.Source), event.Value);
        end

        % Value changed function: icDoubleDotValue
        function icDoubleDotValueValueChanged(app, event)
            app.icDoubleDotValues(find(app.icDoubleDotValue == event.Source)) = event.Value;
            fprintf('Box number %d was changed to %f\n', find(app.icDoubleDotValue == event.Source), event.Value);
        end

        % Value changed function: dEquation
        function dEquationValueChanged(app, event)
            app.dEquations{find(app.dEquation == event.Source)} = event.Value;
            fprintf('Box number %d was changed to %f\n', find(app.dEquation == event.Source), event.Value);
        end

        % Value changed function: IJointValue
        function IJointValueValueChanged(app, event)
            app.IJointValues(find(app.IJointValue == event.Source)) = event.Value;
            fprintf('Box number %d was changed to %f\n', find(app.IJointValue == event.Source), event.Value);
        end

        % Value changed function: mLinkValue
        function mLinkValueValueChanged(app, event)
            app.mLinkValues(find(app.mLinkValue == event.Source)) = event.Value;
            fprintf('Box number %d was changed to %f\n', find(app.mLinkValue == event.Source), event.Value);
        end

        % Value changed function: ILinkValue
        function ILinkValueValueChanged(app, event)
            app.ILinkValues(find(app.ILinkValue == event.Source)) = event.Value;
            fprintf('Box number %d was changed to %f\n', find(app.ILinkValue == event.Source), event.Value);       
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            app.solving = 1;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {752, 752};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {281, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ofJointsSpinnerLabel = uilabel(app.LeftPanel);
            app.ofJointsSpinnerLabel.Text = '# of Joints';
            app.ofJointsSpinner = uispinner(app.LeftPanel);
            app.ofJointsSpinner.ValueChangedFcn = createCallbackFcn(app, @ofJointsSpinnerValueChanged, true);
            app.ofJointsSpinner.Value = 1;
            app.aValue = uieditfield(app.LeftPanel, 'numeric');
            app.aValue.ValueChangedFcn = createCallbackFcn(app, @aValueValueChanged, true);
            app.alphaValue = uieditfield(app.LeftPanel, 'numeric');
            app.alphaValue.ValueChangedFcn = createCallbackFcn(app, @alphaValueValueChanged, true);
            app.dValue = uieditfield(app.LeftPanel, 'numeric');
            app.dValue.ValueChangedFcn = createCallbackFcn(app, @dValueValueChanged, true);
            app.thetaValue = uieditfield(app.LeftPanel, 'numeric');
            app.thetaValue.ValueChangedFcn = createCallbackFcn(app, @thetaValueValueChanged, true);
            app.aLabel = uilabel(app.LeftPanel);
            app.aLabel.Text = 'a';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'α';
            app.dLabel = uilabel(app.LeftPanel);
            app.dLabel.Text = 'd';
            app.thetaLabel = uilabel(app.LeftPanel);
            app.thetaLabel.Text = 'θ';
            app.MAE547Team1FinalProjectLabel = uilabel(app.LeftPanel);
            app.MAE547Team1FinalProjectLabel.Text = 'MAE547 Team 1 Final Project ';
            app.MainMenus = uibuttongroup(app.LeftPanel);
            app.MainMenus.SelectionChangedFcn = createCallbackFcn(app, @MainMenusSelectionChanged, true);
            app.MainMenus.Title = 'Modes';
            app.DynamicsButton = uitogglebutton(app.MainMenus);
            app.DynamicsButton.Text = 'Dynamics';
            app.DynamicsButton.Value = true;
            app.IFCCButton = uitogglebutton(app.MainMenus);
            app.IFCCButton.Text = 'IFC C';
            app.IFCIButton = uitogglebutton(app.MainMenus);
            app.IFCIButton.Text = 'IFC I';
            app.JointsTableLabel = uilabel(app.LeftPanel);
            app.JointsTableLabel.Text = 'Joints Table';
            app.mLinkValue = uieditfield(app.LeftPanel, 'numeric');
            app.mLinkValue.ValueChangedFcn = createCallbackFcn(app, @mLinkValueValueChanged, true);
            app.mLinkValue.Visible = 'off';
            app.ILinkValue = uieditfield(app.LeftPanel, 'numeric');
            app.ILinkValue.ValueChangedFcn = createCallbackFcn(app, @ILinkValueValueChanged, true);
            app.ILinkValue.Visible = 'off';
            app.mLinkLabel = uilabel(app.LeftPanel);
            app.mLinkLabel.Visible = 'off';
            app.mLinkLabel.Text = 'm';
            app.ILinkLabel = uilabel(app.LeftPanel);
            app.ILinkLabel.Visible = 'off';
            app.ILinkLabel.Text = 'I';
            app.LinksTableLabel = uilabel(app.LeftPanel);
            app.LinksTableLabel.Visible = 'off';
            app.LinksTableLabel.Text = 'Links Table';
            app.linkNumber = uilabel(app.LeftPanel);
            app.linkNumber.Visible = 'off';
            app.linkNumber.Text = '1';
            app.ILinkLabel_2 = uilabel(app.LeftPanel);
            app.ILinkLabel_2.Visible = 'off';
            app.ILinkLabel_2.Text = 'g =';
            app.ILinkValue_2 = uieditfield(app.LeftPanel, 'numeric');
            app.ILinkValue_2.Visible = 'off';
            app.ILinkValue_3 = uieditfield(app.LeftPanel, 'numeric');
            app.ILinkValue_3.Visible = 'off';
            app.ILinkValue_4 = uieditfield(app.LeftPanel, 'numeric');
            app.ILinkValue_4.Visible = 'off';
            app.ILinkValue_5 = uieditfield(app.LeftPanel, 'numeric');
            app.ILinkValue_5.Visible = 'off';
            app.ILinkValue_6 = uieditfield(app.LeftPanel, 'numeric');
            app.ILinkValue_6.Visible = 'off';
            app.SolveButton = uibutton(app.LeftPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.RightPanel = uipanel(app.GridLayout);
            app.rpValue = uidropdown(app.UIFigure);
            app.rpValue.Items = {'Revolut', 'Prismatic'};
            app.rpValue.ValueChangedFcn = createCallbackFcn(app, @rpValueValueChanged, true);
            app.rpValue.Value = 'Revolut';
            app.mJointValue = uieditfield(app.UIFigure, 'numeric');
            app.mJointValue.ValueChangedFcn = createCallbackFcn(app, @mJointValueValueChanged, true);
            app.mJointValue.Visible = 'off';
            app.mJointLabel = uilabel(app.UIFigure);
            app.mJointLabel.Visible = 'off';
            app.mJointLabel.Text = 'm';
            app.krValue = uieditfield(app.UIFigure, 'numeric');
            app.krValue.ValueChangedFcn = createCallbackFcn(app, @krValueValueChanged, true);
            app.krValue.Visible = 'off';
            app.sfValue = uieditfield(app.UIFigure, 'numeric');
            app.sfValue.ValueChangedFcn = createCallbackFcn(app, @sfValueValueChanged, true);
            app.sfValue.Visible = 'off';
            app.vfValue = uieditfield(app.UIFigure, 'numeric');
            app.vfValue.ValueChangedFcn = createCallbackFcn(app, @vfValueValueChanged, true);
            app.vfValue.Visible = 'off';
            app.krLabel = uilabel(app.UIFigure);
            app.krLabel.Visible = 'off';
            app.krLabel.Text = 'kᵣ';
            app.sfLabel = uilabel(app.UIFigure);
            app.sfLabel.Visible = 'off';
            app.sfLabel.Text = 'SF';
            app.vfLabel = uilabel(app.UIFigure);
            app.vfLabel.Visible = 'off';
            app.vfLabel.Text = 'VF';
            app.jointNumber = uilabel(app.UIFigure);
            app.jointNumber.Text = '1';
            app.icDotValue = uieditfield(app.UIFigure, 'numeric');
            app.icDotValue.ValueChangedFcn = createCallbackFcn(app, @icDotValueValueChanged, true);
            app.icDotLabel = uilabel(app.UIFigure);
            app.icDotLabel.Interpreter = 'latex';
            app.icDotLabel.Text = '\dot{$\theta_0$}/\dot{$d_0$}';
            app.icDoubleDotValue = uieditfield(app.UIFigure, 'numeric');
            app.icDoubleDotValue.ValueChangedFcn = createCallbackFcn(app, @icDoubleDotValueValueChanged, true);
            app.icDoubleDotLabel = uilabel(app.UIFigure);
            app.icDoubleDotLabel.Interpreter = 'latex';
            app.icDoubleDotLabel.Text = '\ddot{$\theta_0$}/\ddot{$d_0$}';
            app.dEquation = uieditfield(app.UIFigure, 'text');
            app.dEquation.ValueChangedFcn = createCallbackFcn(app, @dEquationValueChanged, true);
            app.IJointValue = uieditfield(app.UIFigure, 'numeric');
            app.IJointValue.ValueChangedFcn = createCallbackFcn(app, @IJointValueValueChanged, true);
            app.IJointValue.Visible = 'off';
            app.IJointLabel = uilabel(app.UIFigure);
            app.IJointLabel.Visible = 'off';
            app.IJointLabel.Text = 'I';
            app.dEquationLabel = uilabel(app.UIFigure);
            app.dEquationLabel.Text = 'Equation';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.aMenu = uimenu(app.ContextMenu);
            app.alphaMenu = uimenu(app.ContextMenu);
            app.dMenu = uimenu(app.ContextMenu);
            app.thetaMenu = uimenu(app.ContextMenu);
            app.rpMenu = uimenu(app.ContextMenu);
            app.mJointMenu = uimenu(app.ContextMenu);
            app.IJointMenu = uimenu(app.ContextMenu);
            app.krMenu = uimenu(app.ContextMenu);
            app.sfMenu = uimenu(app.ContextMenu);
            app.vfMenu = uimenu(app.ContextMenu);
            app.icDotMenu = uimenu(app.ContextMenu);
            app.mLinkMenu = uimenu(app.ContextMenu);
            app.ILinkMenu = uimenu(app.ContextMenu);
            app.icDoubleDotMenu = uimenu(app.ContextMenu);
            app.dEquationMenu = uimenu(app.ContextMenu);
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            app.aValue.ContextMenu = app.ContextMenu;
            app.mLinkValue.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAE547_Final_Project_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end